classdef App_ISEL_Traverse_manuell_v3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ISELTraverseBasicUIFigure   matlab.ui.Figure
        Toolbar                     matlab.ui.container.Toolbar
        PushTool                    matlab.ui.container.toolbar.PushTool
        mLES4Label                  matlab.ui.control.Label
        PanelMoveY                  matlab.ui.container.Panel
        PanelYRep                   matlab.ui.container.Panel
        Image_2                     matlab.ui.control.Image
        Switch_YRepeat              matlab.ui.control.Switch
        EditField_YDelay            matlab.ui.control.NumericEditField
        DelaysEditFieldLabel_2      matlab.ui.control.Label
        EditField_YRepeat           matlab.ui.control.NumericEditField
        RepetitionEditFieldLabel_2  matlab.ui.control.Label
        PanelYAbs                   matlab.ui.container.Panel
        EditField_YAbsSpeed         matlab.ui.control.NumericEditField
        SpeedEditField_3Label_2     matlab.ui.control.Label
        EditField_YPos              matlab.ui.control.NumericEditField
        PositionmmEditFieldLabel_2  matlab.ui.control.Label
        Button_YMoveAbs             matlab.ui.control.StateButton
        PanelYRel                   matlab.ui.container.Panel
        EditField_YRelSpeed         matlab.ui.control.NumericEditField
        SpeedEditField_2Label_2     matlab.ui.control.Label
        EditField_YDist             matlab.ui.control.NumericEditField
        DistancemmEditFieldLabel_2  matlab.ui.control.Label
        Button_YMoveRel             matlab.ui.control.StateButton
        Terminal                    matlab.ui.control.TextArea
        TerminalTextAreaLabel       matlab.ui.control.Label
        PanelSettings               matlab.ui.container.Panel
        EditField_Steps             matlab.ui.control.NumericEditField
        StepsEditFieldLabel         matlab.ui.control.Label
        EditField_Elev              matlab.ui.control.NumericEditField
        ElevationmmEditFieldLabel   matlab.ui.control.Label
        Image3                      matlab.ui.control.Image
        Image2                      matlab.ui.control.Image
        mLES5Label                  matlab.ui.control.Label
        PanelMoveX                  matlab.ui.container.Panel
        PanelXRep                   matlab.ui.container.Panel
        Image                       matlab.ui.control.Image
        Switch_XRepeat              matlab.ui.control.Switch
        EditField_XDelay            matlab.ui.control.NumericEditField
        DelaysEditFieldLabel        matlab.ui.control.Label
        EditField_XRepeat           matlab.ui.control.NumericEditField
        RepetitionEditFieldLabel    matlab.ui.control.Label
        PanelXAbs                   matlab.ui.container.Panel
        EditField_XAbsSpeed         matlab.ui.control.NumericEditField
        SpeedEditField_3Label       matlab.ui.control.Label
        EditField_XPos              matlab.ui.control.NumericEditField
        PositionmmEditFieldLabel    matlab.ui.control.Label
        Button_XMoveAbs             matlab.ui.control.StateButton
        PanelXRel                   matlab.ui.container.Panel
        EditField_XRelSpeed         matlab.ui.control.NumericEditField
        SpeedEditField_2Label       matlab.ui.control.Label
        EditField_XDist             matlab.ui.control.NumericEditField
        DistancemmEditFieldLabel    matlab.ui.control.Label
        Button_XMoveRel             matlab.ui.control.StateButton
        PanelReference              matlab.ui.container.Panel
        EditField_RefSpeed          matlab.ui.control.NumericEditField
        SpeedEditFieldLabel         matlab.ui.control.Label
        Button_RefXY                matlab.ui.control.StateButton
        Button_RefY                 matlab.ui.control.StateButton
        Button_RefX                 matlab.ui.control.StateButton
        PanelAxis                   matlab.ui.container.Panel
        Button_AxisXY               matlab.ui.control.StateButton
        Button_AxisX                matlab.ui.control.StateButton
        PanelConnection             matlab.ui.container.Panel
        EditField_Baudrate          matlab.ui.control.NumericEditField
        BaudrateEditFieldLabel      matlab.ui.control.Label
        EditField_COM               matlab.ui.control.NumericEditField
        COMPortEditFieldLabel       matlab.ui.control.Label
        LampConnection              matlab.ui.control.Lamp
        SwitchConnection            matlab.ui.control.Switch
    end


    properties (Access = public)
        device
    end

    methods (Access = private)

        function funcRef(app, buttonRef, refSpeed, axis)
            if buttonRef.Value == 1
                try
                    t_pause = 0.1;
                    if axis == "x"
                        writeline(app.device,"@01");    % 1 Achsen aktivieren
                    elseif axis == "y" || axis == "xy"
                        writeline(app.device,"@02");    % 2 Achsen
                    end
                    pause(t_pause);
                    writeline(app.device,['@0d',num2str(refSpeed)]);    % Ref speed setzen
                    pause(t_pause);
                    writeline(app.device,"@0i");                  % #input Befehl
                    pause(t_pause);
                    if axis == "x"
                        writeline(app.device,"71");                   % X Achse referenzieren
                    elseif axis == "y"
                        writeline(app.device,"72");                   % Y Achse referenzieren
                    elseif axis == "xy"
                        writeline(app.device,"71");                   % X Achse referenzieren
                    end
                    pause(t_pause);
                    writeline(app.device,"9");                    % stop. Befehl
                    pause(t_pause);
                    writeline(app.device,"@0S");                  % #start Befehl
                    buttonRef.Value = 0;
                    app.Terminal.Value = ['-- Reference ',axis;...
                        app.Terminal.Value];
                catch ME
                    app.Terminal.Value = ['-- ',ME.message;...
                        app.Terminal.Value];

                end
            end
        end

        function funcMoveRel(app,buttonMoveRel, dist, elev, steps, axisSpeed, axis)
            if buttonMoveRel.Value == 1
                try
                    t_pause = 0.1;
                    if axis == "x"
                        writeline(app.device,"@01");    % 1 Achsen aktivieren
                    elseif axis == "y"
                        writeline(app.device,"@02");    % 2 Achsen
                    end
                    pause(t_pause);
                    writeline(app.device,"@0i");                  % #input Befehl
                    pause(t_pause);
                    if axis == "x"
                        writeline(app.device,['0',...
                            num2str(steps/elev*dist),',',num2str(axisSpeed),...
                            ',0,',num2str(axisSpeed)]);        % move relative
                    elseif axis == "y"
                        writeline(app.device,['0',...
                            '0,',num2str(axisSpeed),',',...
                            num2str(steps/elev*dist),',',num2str(axisSpeed)]);        % move relative
                    end
                    pause(t_pause);
                    writeline(app.device,"9");                    % stop. Befehl
                    pause(t_pause);
                    writeline(app.device,"@0S");                  % #start Befehl
                    buttonMoveRel.Value = 0;
                    app.Terminal.Value = ['-- Move ',axis,' axis relative: ',num2str(dist),' mm';...
                        app.Terminal.Value];
                catch ME
                    app.Terminal.Value = ['-- ',ME.message;...
                        app.Terminal.Value];

                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SwitchConnection
        function SwitchConnectionValueChanged(app, event)
            value = app.SwitchConnection.Value;

            if value == "On"
                try
                    app.device = serialport(['COM',num2str(app.EditField_COM.Value)], app.EditField_Baudrate.Value);
                    app.Terminal.Value = ['-- Connected';...
                        app.Terminal.Value];
                    app.LampConnection.Color = [0.39,0.83,0.07];
                    configureTerminator(app.device,"CR/LF")      % unbedingt notwendig, für ECHO Antwort
                    app.Terminal.Value = ['-- Device terminator set to CR/LF';...
                        app.Terminal.Value];
                    pause(0.1);
                    app.device.Timeout = 10; % default 10
                    pause(0.1);
                    app.PanelAxis.Enable = "On";
                    app.Terminal.Value = ['-- Enabled axis panel';...
                        app.Terminal.Value];

                catch ME
                    app.Terminal.Value = ['-- ',ME.message;...
                        app.Terminal.Value];
                end
            else
                try
                    %                 close serial port
                    delete(app.device);
                    app.LampConnection.Color = [1,0,0];
                    app.Terminal.Value = ['-- Disconnected';...
                        app.Terminal.Value];
                catch ME
                    app.Terminal.Value = ['-- ',ME.message;...
                        app.Terminal.Value];
                end
            end

        end

        % Value changed function: Button_AxisXY
        function Button_AxisXYValueChanged(app, event)
            value = app.Button_AxisXY.Value;

            if value == 1
                app.Button_AxisX.Value = 0;
                app.PanelMoveX.Enable = "On";
                app.PanelMoveY.Enable = "On";
                app.Button_RefX.Enable = "On";
                app.Button_RefY.Enable = "On";
                app.Button_RefXY.Enable = "On";
                app.Terminal.Value = ['-- Enabled axis x and y';...
                    app.Terminal.Value];
            else
                app.PanelMoveX.Enable = "Off";
                app.PanelMoveY.Enable = "Off";
                app.Button_RefX.Enable = "Off";
                app.Button_RefY.Enable = "Off";
                app.Button_RefXY.Enable = "Off";
                app.Terminal.Value = ['-- Disabled axis x and y';...
                    app.Terminal.Value];
            end
        end

        % Callback function
        function Button_AxisYValueChanged(app, event)

        end

        % Value changed function: Button_AxisX
        function Button_AxisXValueChanged(app, event)
            value = app.Button_AxisX.Value;

            if value == 1
                app.Button_AxisXY.Value = 0;
                app.PanelMoveX.Enable = "On";
                app.Button_RefX.Enable = "On";
                app.Terminal.Value = ['-- Enabled axis x';...
                    app.Terminal.Value];
                app.PanelMoveY.Enable = "Off";
                app.Button_RefY.Enable = "Off";
                app.Button_RefXY.Enable = "Off";
            else
                app.PanelMoveX.Enable = "Off";
                app.Button_RefX.Enable = "Off";
                app.Terminal.Value = ['-- Disabled axis x';...
                    app.Terminal.Value];

            end
        end

        % Value changed function: Button_RefX
        function Button_RefXValueChanged(app, event)
            funcRef(app,app.Button_RefX, app.EditField_RefSpeed.Value,app.Button_RefX.Tag)

        end

        % Value changed function: Button_RefY
        function Button_RefYValueChanged(app, event)
            funcRef(app,app.Button_RefY, app.EditField_RefSpeed.Value,app.Button_RefY.Tag)

        end

        % Value changed function: Button_RefXY
        function Button_RefXYValueChanged(app, event)
            funcRef(app,app.Button_RefXY, app.EditField_RefSpeed.Value,app.Button_RefXY.Tag)

        end

        % Value changed function: Button_XMoveRel
        function Button_XMoveRelValueChanged(app, event)
            funcMoveRel(app,app.Button_XMoveRel,app.EditField_XDist.Value, ...
                app.EditField_Elev.Value,app.EditField_Steps.Value, ...
                app.EditField_XRelSpeed.Value,app.Button_XMoveRel.Tag)

        end

        % Value changed function: Button_YMoveRel
        function Button_YMoveRelValueChanged(app, event)
            funcMoveRel(app,app.Button_YMoveRel,app.EditField_YDist.Value,...
                app.EditField_Elev.Value,app.EditField_Steps.Value,...
                app.EditField_YRelSpeed.Value,app.Button_YMoveRel.Tag)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ISELTraverseBasicUIFigure = uifigure('Visible', 'off');
            app.ISELTraverseBasicUIFigure.Name = 'ISEL Traverse (Basic)';
            app.Toolbar = uitoolbar(app.ISELTraverseBasicUIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PanelConnection = uipanel(app.ISELTraverseBasicUIFigure);
            app.PanelConnection.Title = '1. Connection';
            app.SwitchConnection = uiswitch(app.PanelConnection, 'slider');
            app.SwitchConnection.ValueChangedFcn = createCallbackFcn(app, @SwitchConnectionValueChanged, true);
            app.LampConnection = uilamp(app.PanelConnection);
            app.COMPortEditFieldLabel = uilabel(app.PanelConnection);
            app.COMPortEditFieldLabel.Text = 'COM Port';
            app.EditField_COM = uieditfield(app.PanelConnection, 'numeric');
            app.EditField_COM.Value = 3;
            app.BaudrateEditFieldLabel = uilabel(app.PanelConnection);
            app.BaudrateEditFieldLabel.Text = 'Baudrate';
            app.EditField_Baudrate = uieditfield(app.PanelConnection, 'numeric');
            app.EditField_Baudrate.ValueDisplayFormat = '%11.0f';
            app.EditField_Baudrate.Editable = 'off';
            app.EditField_Baudrate.Value = 19200;
            app.PanelAxis = uipanel(app.ISELTraverseBasicUIFigure);
            app.PanelAxis.Enable = 'off';
            app.PanelAxis.Title = 'Axis';
            app.Button_AxisX = uibutton(app.PanelAxis, 'state');
            app.Button_AxisX.ValueChangedFcn = createCallbackFcn(app, @Button_AxisXValueChanged, true);
            app.Button_AxisX.Tag = 'x';
            app.Button_AxisX.Text = 'X';
            app.Button_AxisXY = uibutton(app.PanelAxis, 'state');
            app.Button_AxisXY.ValueChangedFcn = createCallbackFcn(app, @Button_AxisXYValueChanged, true);
            app.Button_AxisXY.Tag = 'xy';
            app.Button_AxisXY.Text = 'X, Y';
            app.PanelReference = uipanel(app.ISELTraverseBasicUIFigure);
            app.PanelReference.Title = 'Reference';
            app.Button_RefX = uibutton(app.PanelReference, 'state');
            app.Button_RefX.ValueChangedFcn = createCallbackFcn(app, @Button_RefXValueChanged, true);
            app.Button_RefX.Tag = 'x';
            app.Button_RefX.Enable = 'off';
            app.Button_RefX.Text = 'X';
            app.Button_RefY = uibutton(app.PanelReference, 'state');
            app.Button_RefY.ValueChangedFcn = createCallbackFcn(app, @Button_RefYValueChanged, true);
            app.Button_RefY.Tag = 'y';
            app.Button_RefY.Enable = 'off';
            app.Button_RefY.Text = 'Y';
            app.Button_RefXY = uibutton(app.PanelReference, 'state');
            app.Button_RefXY.ValueChangedFcn = createCallbackFcn(app, @Button_RefXYValueChanged, true);
            app.Button_RefXY.Tag = 'xy';
            app.Button_RefXY.Enable = 'off';
            app.Button_RefXY.Text = 'X, Y';
            app.SpeedEditFieldLabel = uilabel(app.PanelReference);
            app.SpeedEditFieldLabel.Text = 'Speed';
            app.EditField_RefSpeed = uieditfield(app.PanelReference, 'numeric');
            app.EditField_RefSpeed.Value = 5000;
            app.PanelMoveX = uipanel(app.ISELTraverseBasicUIFigure);
            app.PanelMoveX.Enable = 'off';
            app.PanelMoveX.Title = 'Move X';
            app.PanelXRel = uipanel(app.PanelMoveX);
            app.PanelXRel.Title = 'Relative';
            app.Button_XMoveRel = uibutton(app.PanelXRel, 'state');
            app.Button_XMoveRel.ValueChangedFcn = createCallbackFcn(app, @Button_XMoveRelValueChanged, true);
            app.Button_XMoveRel.Tag = 'x';
            app.Button_XMoveRel.Text = 'Move';
            app.DistancemmEditFieldLabel = uilabel(app.PanelXRel);
            app.DistancemmEditFieldLabel.Text = 'Distance [mm]';
            app.EditField_XDist = uieditfield(app.PanelXRel, 'numeric');
            app.SpeedEditField_2Label = uilabel(app.PanelXRel);
            app.SpeedEditField_2Label.Text = 'Speed';
            app.EditField_XRelSpeed = uieditfield(app.PanelXRel, 'numeric');
            app.EditField_XRelSpeed.Value = 5000;
            app.PanelXAbs = uipanel(app.PanelMoveX);
            app.PanelXAbs.Enable = 'off';
            app.PanelXAbs.Title = 'Absolute';
            app.Button_XMoveAbs = uibutton(app.PanelXAbs, 'state');
            app.Button_XMoveAbs.Tag = 'x';
            app.Button_XMoveAbs.Text = 'Move';
            app.PositionmmEditFieldLabel = uilabel(app.PanelXAbs);
            app.PositionmmEditFieldLabel.Text = 'Position [mm]';
            app.EditField_XPos = uieditfield(app.PanelXAbs, 'numeric');
            app.SpeedEditField_3Label = uilabel(app.PanelXAbs);
            app.SpeedEditField_3Label.Text = 'Speed';
            app.EditField_XAbsSpeed = uieditfield(app.PanelXAbs, 'numeric');
            app.EditField_XAbsSpeed.Value = 5000;
            app.PanelXRep = uipanel(app.PanelMoveX);
            app.PanelXRep.Enable = 'off';
            app.PanelXRep.Title = 'Relative Repeat';
            app.RepetitionEditFieldLabel = uilabel(app.PanelXRep);
            app.RepetitionEditFieldLabel.Text = 'Repetition';
            app.EditField_XRepeat = uieditfield(app.PanelXRep, 'numeric');
            app.EditField_XRepeat.Value = 1;
            app.DelaysEditFieldLabel = uilabel(app.PanelXRep);
            app.DelaysEditFieldLabel.Text = 'Delay [s]';
            app.EditField_XDelay = uieditfield(app.PanelXRep, 'numeric');
            app.EditField_XDelay.Value = 2;
            app.Switch_XRepeat = uiswitch(app.PanelXRep, 'slider');
            app.Image = uiimage(app.PanelXRep);
            app.Image.ImageSource = 'symbolRepeat.png';
            app.mLES5Label = uilabel(app.ISELTraverseBasicUIFigure);
            app.mLES5Label.Text = '3 m (LES5)';
            app.Image2 = uiimage(app.ISELTraverseBasicUIFigure);
            app.Image2.ImageSource = 'wawi-logo.png';
            app.Image3 = uiimage(app.ISELTraverseBasicUIFigure);
            app.Image3.ImageSource = 'isel-logo.png';
            app.PanelSettings = uipanel(app.ISELTraverseBasicUIFigure);
            app.PanelSettings.Title = 'Settings';
            app.ElevationmmEditFieldLabel = uilabel(app.PanelSettings);
            app.ElevationmmEditFieldLabel.Text = 'Elevation [mm]';
            app.EditField_Elev = uieditfield(app.PanelSettings, 'numeric');
            app.EditField_Elev.Editable = 'off';
            app.EditField_Elev.Value = 2.5;
            app.StepsEditFieldLabel = uilabel(app.PanelSettings);
            app.StepsEditFieldLabel.Text = 'Steps';
            app.EditField_Steps = uieditfield(app.PanelSettings, 'numeric');
            app.EditField_Steps.Editable = 'off';
            app.EditField_Steps.Value = 800;
            app.TerminalTextAreaLabel = uilabel(app.ISELTraverseBasicUIFigure);
            app.TerminalTextAreaLabel.Text = 'Terminal';
            app.Terminal = uitextarea(app.ISELTraverseBasicUIFigure);
            app.Terminal.Editable = 'off';
            app.Terminal.Value = {'-- Please connect...'};
            app.PanelMoveY = uipanel(app.ISELTraverseBasicUIFigure);
            app.PanelMoveY.Enable = 'off';
            app.PanelMoveY.Title = 'Move Y';
            app.PanelYRel = uipanel(app.PanelMoveY);
            app.PanelYRel.Title = 'Relative';
            app.Button_YMoveRel = uibutton(app.PanelYRel, 'state');
            app.Button_YMoveRel.ValueChangedFcn = createCallbackFcn(app, @Button_YMoveRelValueChanged, true);
            app.Button_YMoveRel.Tag = 'y';
            app.Button_YMoveRel.Text = 'Move';
            app.DistancemmEditFieldLabel_2 = uilabel(app.PanelYRel);
            app.DistancemmEditFieldLabel_2.Text = 'Distance [mm]';
            app.EditField_YDist = uieditfield(app.PanelYRel, 'numeric');
            app.SpeedEditField_2Label_2 = uilabel(app.PanelYRel);
            app.SpeedEditField_2Label_2.Text = 'Speed';
            app.EditField_YRelSpeed = uieditfield(app.PanelYRel, 'numeric');
            app.EditField_YRelSpeed.Value = 5000;
            app.PanelYAbs = uipanel(app.PanelMoveY);
            app.PanelYAbs.Enable = 'off';
            app.PanelYAbs.Title = 'Absolute';
            app.Button_YMoveAbs = uibutton(app.PanelYAbs, 'state');
            app.Button_YMoveAbs.Tag = 'y';
            app.Button_YMoveAbs.Text = 'Move';
            app.PositionmmEditFieldLabel_2 = uilabel(app.PanelYAbs);
            app.PositionmmEditFieldLabel_2.Text = 'Position [mm]';
            app.EditField_YPos = uieditfield(app.PanelYAbs, 'numeric');
            app.SpeedEditField_3Label_2 = uilabel(app.PanelYAbs);
            app.SpeedEditField_3Label_2.Text = 'Speed';
            app.EditField_YAbsSpeed = uieditfield(app.PanelYAbs, 'numeric');
            app.EditField_YAbsSpeed.Value = 5000;
            app.PanelYRep = uipanel(app.PanelMoveY);
            app.PanelYRep.Enable = 'off';
            app.PanelYRep.Title = 'Relative Repeat';
            app.RepetitionEditFieldLabel_2 = uilabel(app.PanelYRep);
            app.RepetitionEditFieldLabel_2.Text = 'Repetition';
            app.EditField_YRepeat = uieditfield(app.PanelYRep, 'numeric');
            app.EditField_YRepeat.Value = 1;
            app.DelaysEditFieldLabel_2 = uilabel(app.PanelYRep);
            app.DelaysEditFieldLabel_2.Text = 'Delay [s]';
            app.EditField_YDelay = uieditfield(app.PanelYRep, 'numeric');
            app.EditField_YDelay.Value = 2;
            app.Switch_YRepeat = uiswitch(app.PanelYRep, 'slider');
            app.Image_2 = uiimage(app.PanelYRep);
            app.Image_2.ImageSource = 'symbolRepeat.png';
            app.mLES4Label = uilabel(app.ISELTraverseBasicUIFigure);
            app.mLES4Label.Text = '1 m (LES4)';
            % Show the figure after all components are created
            app.ISELTraverseBasicUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_ISEL_Traverse_manuell_v3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ISELTraverseBasicUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ISELTraverseBasicUIFigure)
        end
    end
end